classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        CloseMenu                  matlab.ui.container.Menu
        ResetMenu                  matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        RedoMenu                   matlab.ui.container.Menu
        UndoMenu                   matlab.ui.container.Menu
        filters                    matlab.ui.container.Menu
        MoyenMenu                  matlab.ui.container.Menu
        GaussienMenu               matlab.ui.container.Menu
        MedianMenu                 matlab.ui.container.Menu
        SharpenMenu                matlab.ui.container.Menu
        morphologicalMenu          matlab.ui.container.Menu
        NoiseMenu                  matlab.ui.container.Menu
        SaltPepperMenu             matlab.ui.container.Menu
        GuassianMenu               matlab.ui.container.Menu
        sinusoidalMenu             matlab.ui.container.Menu
        DetectionMenu              matlab.ui.container.Menu
        EdgeMenu                   matlab.ui.container.Menu
        SobelMenu                  matlab.ui.container.Menu
        cannyMenu                  matlab.ui.container.Menu
        prewittMenu                matlab.ui.container.Menu
        LaplacianofGaussianMenu    matlab.ui.container.Menu
        RGBMenu                    matlab.ui.container.Menu
        seuillageMenu              matlab.ui.container.Menu
        graph                      matlab.ui.container.Menu
        HistogramMenu              matlab.ui.container.Menu
        HistogramequalizationMenu  matlab.ui.container.Menu
        FourierTransform           matlab.ui.container.Menu
        selectMenu                 matlab.ui.container.Menu
        TextMenu                   matlab.ui.container.Menu
        TransformMenu              matlab.ui.container.Menu
        adjustmentMenu             matlab.ui.container.Menu
        BrightnessMenu             matlab.ui.container.Menu
        ContrastMenu               matlab.ui.container.Menu
        SaturationMenu             matlab.ui.container.Menu
        UIAxes1                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        History;
        UndoIndex;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
                  
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            
            [file,path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp'});
            if isequal(file,0)
                return;
            end
            img = imread(fullfile(path,file));
            setappdata(app.UIFigure,'OriginalImg',img);
            axis(app.UIAxes1,'tight');
            imshow(img,'Parent',app.UIAxes1);
            stack = {};
            stack{end+1} = getimage(app.UIAxes1);
            setappdata(0,'stack',stack);

            stack2 = {};
            stack2{end+1} = getimage(app.UIAxes1);
            setappdata(0,'stack2',stack2);

        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            delete(app.UIFigure);
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
             orgImg = getappdata(app.UIFigure,'OriginalImg');
            if isempty(orgImg) 
                errordlg('please load an image first');
                return;
            end   
            imshow(orgImg,'Parent',app.UIAxes1);
            %cla(app.UIAxes2);
        end

        % Menu selected function: HistogramMenu
        function HistogramMenuSelected(app, event)
            %orgImg = getappdata(app.UIFigure,'OriginalImg');
            % Get image from axes1
            orgImg = getimage(app.UIAxes1);
            if isempty(orgImg) 
                errordlg('please load an image first');
                return;
            end    
               % Create a new figure window
                histogramFigure = figure;
                % Plot the histogram
                imhist(orgImg);
            
                % Set the title of the new figure window
                title('Histogram of Image');
        end

        % Menu selected function: HistogramequalizationMenu
        function HistogramequalizationMenuSelected(app, event)
            % Get image from axes1
            orgImg = getimage(app.UIAxes1);
            if isempty(orgImg) 
                errordlg('please load an image first');
                return;
            end    
            equalizedImg = histeq(orgImg);
            imshow(equalizedImg,'Parent',app.UIAxes1)
               % Create a new figure window
                histogramFigure = figure;
               
                % Plot the histogram
                imhist(equalizedImg);
            
                % Set the title of the new figure window
                title('Histogram of Image');
        end

        % Menu selected function: SaltPepperMenu
        function SaltPepperMenuSelected(app, event)
             % Get image from axes1
            originalImage= getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            %Ask the user for the noise density
            density = inputdlg('Enter the noise density [0-1]:');
            density = str2double(density{1});
            % Add salt and pepper noise
            noisyImage = imnoise(originalImage, 'salt & pepper', density);
            axis(app.UIAxes1,'tight');
            % Display the noisy image
            imshow(noisyImage, 'Parent', app.UIAxes1);
            
            stack = getappdata(0,'stack');
            stack{end+1} = getimage(app.UIAxes1);
            setappdata(0,'stack',stack);
       
    
        end

        % Menu selected function: GuassianMenu
        function GuassianMenuSelected(app, event)
             % check if there is an original image loaded
            originalImage = getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            %Ask the user for the mean and variance
            mean = inputdlg('Enter the mean [0-1] :');
            mean = str2double(mean{1});
            variance = inputdlg('Enter the variance [0-1] : ');
            variance = str2double(variance{1});
            % Add Gaussian noise
            noisyImage = imnoise(originalImage, 'gaussian', mean, variance);
            % Display the noisy image
            imshow(noisyImage, 'Parent', app.UIAxes1);
        end

        % Menu selected function: sinusoidalMenu
        function sinusoidalMenuSelected(app, event)
             % check if there is an original image loaded
            originalImage = getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            % Ask the user for the amplitude and frequency
            amplitude = inputdlg('Enter the amplitude [0-1] :');
            amplitude = str2double(amplitude{1});
            frequency = inputdlg('Enter the frequency [0-1]:');
            frequency = str2double(frequency{1});
            originalImage = double(originalImage);
            sinusoidal_noise = amplitude * sin(2*pi*frequency*randn(size(originalImage)));
            noisyImage = originalImage + sinusoidal_noise;
       
            axis(app.UIAxes1,'tight');
            % Display the noisy image
            imshow(noisyImage, 'Parent', app.UIAxes1);
        end

        % Menu selected function: MoyenMenu
        function MoyenMenuSelected(app, event)
             % check if there is an original image loaded
            originalImage = getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            m = imnoise(originalImage,'gaussian',0,0.03);
            filterSize = inputdlg('enter the size of moyen filter example {3}');
            filterSize = str2double(filterSize{1});
            fw = ones(filterSize) / (filterSize*filterSize);
            filteredImg = imfilter(m,fw);
            axis(app.UIAxes1,'tight');
            imshow(filteredImg, 'Parent', app.UIAxes1);
        end

        % Menu selected function: MedianMenu
        function MedianMenuSelected(app, event)
              % check if there is an original image loaded
            originalImage = getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            filterSize = inputdlg('enter the size of the filter example {3}');
            filterSize = str2double(filterSize{1});
            originalImage = rgb2gray(originalImage);
            filteredImg = medfilt2(originalImage,[filterSize filterSize]);
            axis(app.UIAxes1,'tight');
            imshow(filteredImg, 'Parent', app.UIAxes1);
        end

        % Menu selected function: GaussienMenu
        function GaussienMenuSelected(app, event)
            originalImage = getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            sigma = inputdlg('enter the sigma value example {2}');
            sigma = str2double(sigma{1});
            filteredImg = imgaussfilt(originalImage,sigma);
            axis(app.UIAxes1,'tight');
            imshow(filteredImg, 'Parent', app.UIAxes1);
        end

        % Callback function
        function cercleMenuSelected(app, event)
            originalImage = getappdata(app.UIFigure,'OriginalImg');
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            st1 = [1 1 1
                   1 1 1
                   1 1 1];

             filteredImg = imerode(originalImage,st1); 
             axis(app.UIAxes1,'tight');
            imshow(filteredImg, 'Parent', app.UIAxes1);
        end

        % Callback function
        function cubeMenuSelected(app, event)
            originalImage = getappdata(app.UIFigure,'OriginalImg');
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            st1 = [0 1 0
                   1 1 1
                   0 1 0];

             filteredImg = imerode(originalImage,st1); 
             axis(app.UIAxes1,'tight');
            imshow(filteredImg, 'Parent', app.UIAxes1);
        end

        % Callback function
        function MenuSelected(app, event)
             originalImage = getappdata(app.UIFigure,'OriginalImg');
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            st1 = [0 1 0
                   0 1 0
                   0 1 0];

             filteredImg = imerode(originalImage,st1); 
             axis(app.UIAxes1,'tight');
            imshow(filteredImg, 'Parent', app.UIAxes1);
        end

        % Callback function
        function Menu_2Selected(app, event)
           
        end

        % Callback function
        function SeuillageMenuSelected(app, event)
            originalImage = getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            seuil = inputdlg('enter the seuil value');
            seuil = str2double(seuil{1});
            filteredImg = im2bw(originalImage,seuil/255);
            axis(app.UIAxes1,'tight');
            imshow(filteredImg, 'Parent', app.UIAxes1);
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
             originalImage = getappdata(app.UIFigure,'OriginalImg');
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            originalImage = rgb2gray(originalImage);
            filteredImg = edge(originalImage,"sobel");
            axis(app.UIAxes1,'tight');
            imshow(filteredImg, 'Parent', app.UIAxes1);
        end

        % Menu selected function: cannyMenu
        function cannyMenuSelected(app, event)
             originalImage = getappdata(app.UIFigure,'OriginalImg');
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            originalImage = rgb2gray(originalImage);
            filteredImg = edge(originalImage,"canny");
            axis(app.UIAxes1,'tight');
            imshow(filteredImg, 'Parent', app.UIAxes1);
        end

        % Menu selected function: prewittMenu
        function prewittMenuSelected(app, event)
             originalImage = getappdata(app.UIFigure,'OriginalImg');
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            originalImage = rgb2gray(originalImage);
            filteredImg = edge(originalImage,"prewitt");
            axis(app.UIAxes1,'tight');
            imshow(filteredImg, 'Parent', app.UIAxes1);
        end

        % Menu selected function: RGBMenu
        function RGBMenuSelected(app, event)
             originalImage = getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            grayImg = rgb2gray(originalImage);
            grayImg = im2double(grayImg);
            colorFilters = {'red' , 'green' , 'blue'};
            [colorIndex,v] = listdlg('PromptString','select a color filter:',...,
                                       'SelectionMode','single',...,
                                        'ListString',colorFilters);
            if v == 0 
                return;
            end
            FI = cat(3,grayImg,grayImg,grayImg);
            switch colorFilters{colorIndex}
                case 'red'
                    FI(:, :,2:3) = 0;
                case 'green'  
                    FI(:,:,[1 3]) = 0;
                case 'blue'    
                      FI(:,:,1:2) = 0; 
            end
            axis(app.UIAxes1,'tight');
            imshow(FI, 'Parent', app.UIAxes1);
        end

        % Callback function
        function FlipMenuSelected(app, event)
            originalImage = getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            flipD = {'Horizontally' , 'Verticaly'};
            [DIndex,v] = listdlg('PromptString','select a FLIP DIRECTION :',...,
                                      'SelectionMode','single',...,
                                        'ListString',flipD);
            if v == 0 
                return;
            end
           
            switch flipD{DIndex}
                case 'Horizontally'
                    FI = fliplr(originalImage);
                case 'Verticaly'
                    FI = flipud(originalImage);    
            end  
            axis(app.UIAxes1,'tight');
            imshow(FI, 'Parent', app.UIAxes1);
        end

        % Callback function
        function RotateMenuSelected(app, event)
              % check if there is an original image loaded
            originalImage = getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            angle = inputdlg('enter the angle of rotation');
            angle = str2double(angle{1});
            rotateImg = imrotate(originalImage,angle);
            axis(app.UIAxes1,'tight');
            imshow(rotateImg, 'Parent', app.UIAxes1);
        end

        % Menu selected function: seuillageMenu
        function seuillageMenuSelected(app, event)
             originalImage = getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
            seuil = inputdlg('enter the seuil value');
            seuil = str2double(seuil{1});
            filteredImg = im2bw(originalImage,seuil/255);
            axis(app.UIAxes1,'tight');
            imshow(filteredImg, 'Parent', app.UIAxes1);
        end

        % Callback function
        function OtherMenuSelected(app, event)
         
        end

        % Callback function
        function ExampleMenuSelected(app, event)
            a=[
                0   0   0   1   0   0   0
                0   1   1   1   1   1   0
                0   1   0   1   1   1   0
                1   1   1   1   1   1   0
                0   0   1   1   1   1   0
                0   1   1   1   1   1   0
                0   0   0   0   0   1   0];
                  
                st1=[1 1 1 
                     1 1 1
                     1 1 1];
                st2=[0 1 0 
                     1 1 1
                     0 1 0];
                st3=[0 1 0 
                     0 1 0
                     0 1 0]; 
                st4=[0 0 0 
                     1 1 1
                     0 0 0]; 

      imshow(a , 'Parent',app.UIAxes1);          
     
    % Create a new figure window
    morphologicalFilterFigure = figure;


    % Create a dropdown menu for the filter options
    filterDropdown = uicontrol('Style', 'popup',...
    'String', {'Erosion Cube', 'Erosion Circle', 'Erosion |', 'Erosion ---'},...
    'Position', [20 20 200 50]);
    
    % Add a button for the user to apply the selected filter
    applyFilterButton = uicontrol('Style', 'pushbutton',...
    'String', 'Apply Filter',...
    'Position', [20 70 200 50],...
    'Callback', @applyFilter);
    
    function applyFilter(src, event)
        % Get the selected filter option
        selectedFilter = filterDropdown.String{filterDropdown.Value};
        
        % Get the image data from the axes
        img = getimage(app.UIAxes1);
    if isempty(img)
               errordlg('Please load an image first')
               return
    end
        
        % Perform the selected filter
        switch selectedFilter
            case 'Erosion Cube'
                filteredImg = imerode(img, st1);
            case 'Erosion Circle'
                filteredImg = imerode(img, st2);
            case 'Erosion |'
                filteredImg = imerode(img, st3);
            case 'Erosion ---'
                filteredImg = imerode(img, st4);
        end
        
        %display the filtered image in the axes2
         imshow(filteredImg,'Parent',app.UIAxes1);
        % Close the morphological filter figure window
        %close(morphologicalFilterFigure);
    end

        end

        % Callback function
        function ApplytoImageMenuSelected(app, event)
            % check if there is an original image loaded
            originalImage = app.UIAxes1.Children.CData;
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
         
            st1=[1 1 1 
                     1 1 1
                     1 1 1];
                st2=[0 1 0 
                     1 1 1
                     0 1 0];
                st3=[0 1 0 
                     0 1 0
                     0 1 0]; 
                st4=[0 0 0 
                     1 1 1
                     0 0 0]; 


    % Create a new figure window
    morphologicalFilterFigure = figure;
    
    % Create a dropdown menu for the filter options
    filterDropdown = uicontrol('Style', 'popup',...
    'String', {'Erosion Cube', 'Erosion Circle', 'Erosion |', 'Erosion ---'},...
    'Position', [20 20 200 50]);
    
    % Add a button for the user to apply the selected filter
    applyFilterButton = uicontrol('Style', 'pushbutton',...
    'String', 'Apply Filter',...
    'Position', [20 70 200 50],...
    'Callback', @applyFilter);
    
    function applyFilter(src, event)
        % Get the selected filter option
        selectedFilter = filterDropdown.String{filterDropdown.Value};
        
        
        % Perform the selected filter
        switch selectedFilter
            case 'Erosion Cube'
                filteredImg = imerode(originalImage, st1);
            case 'Erosion Circle'
                filteredImg = imerode(originalImage, st2);
            case 'Erosion |'
                filteredImg = imerode(originalImage, st3);
            case 'Erosion ---'
                filteredImg = imerode(originalImage, st4);
        end
        
        %display the filtered image in the axes2
         imshow(filteredImg,'Parent',app.UIAxes1);
        % Close the morphological filter figure window
        %close(morphologicalFilterFigure);
    end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
             % check if there is an image displayed in the axes2
                currentImage = getimage(app.UIAxes1);
                if isempty(currentImage)
                   errordlg('No image to save')
                   return
                end
                % Ask the user for the file name
                [fileName, filePath] = uiputfile({'*.jpg;*.jpeg;*.png;*.bmp'},'Save the image');
                if fileName==0
                   return
                end
                % Save the image
                imwrite(currentImage, fullfile(filePath, fileName));
                % Show a message
                msgbox(['Image saved as ' fileName], 'Success','help');
        end

        % Callback function
        function MenuSelected2(app, event)


        end

        % Menu selected function: FourierTransform
        function FourierTransformMenuSelected(app, event)
            % Get the current image from UIAxes1
            currentImage = getimage(app.UIAxes1);
    if isempty(currentImage)
               errordlg('Please load an image first')
               return
    end
            % Open a dialog box to allow the user to select a second image
            [fileName, filePath] = uigetfile('*.jpg;*.jpeg;*.png;*.bmp', 'Select a second image');
            secondImage = imread(fullfile(filePath, fileName));
             % check the size of the images 
            [r1,c1,~]=size(currentImage);
            [r2,c2,~]=size(secondImage);
            % check if the images have the same size
            if r1 ~= r2 || c1 ~= c2
            % if not resize them to the same size 
                currentImage = imresize(currentImage,[r2 c2]);
            end
            % Perform the Fourier Transform on the two images
            currentImageFourier = fft2(double(currentImage));
            secondImageFourier = fft2(double(secondImage));
            % Get the amplitude of the current image and the phase of the second image
            amplitudeImage = abs(currentImageFourier);
            phaseImage = angle(secondImageFourier);
            % Mix the amplitude of the current image with the phase of the second image
            mixedImageFourier = amplitudeImage .* exp(1i*phaseImage);
            % Perform the inverse Fourier Transform on the mixed image
            mixedImage = ifft2(mixedImageFourier);
            % Display the mixed image in UIAxes1
            imshow(mixedImage, 'Parent', app.UIAxes1);
        end

        % Callback function
        function LayersMenuSelected(app, event)

        end

        % Menu selected function: selectMenu
        function selectMenuSelected(app, event)
             % Get the original image from uiaxes1
            img = getimage(app.UIAxes1);
    if isempty(img)
               errordlg('Please load an image first')
               return
    end
            % Show the original image in uiaxes1
            imshow(img, 'Parent', app.UIAxes1);
            
            % Let the user select a portion of the image
            rect = getrect(app.UIAxes1);
            
            % Crop the selected portion of the image
            x = round(rect(1));
            y = round(rect(2));
            w = round(rect(3));
            h = round(rect(4));
            img_cropped = imcrop(img, [x, y, w, h]);
            
            % Display the cropped image in uiaxes1
            imshow(img_cropped, 'Parent', app.UIAxes1);
        end

        % Menu selected function: SharpenMenu
        function SharpenMenuSelected(app, event)
            % Get the original image from uiaxes1
            img = getimage(app.UIAxes1);
    if isempty(img)
               errordlg('Please load an image first')
               return
    end
            
            % Prompt the user to specify the sharpness value
            answer = inputdlg('Enter the sharpness value (e.g. 0.5):');
            
            % Convert the input to a number
            sharpness_value = str2double(answer{1});
            
            % Create a sharpening kernel
            kernel = [0 -1 0; -1 4 -1; 0 -1 0];
            
            % Perform convolution between the image and the kernel
            sharpened_img = imfilter(img, kernel);
            
            % Scale the sharpened image with the specified alpha value
            sharpened_img = img + sharpness_value * sharpened_img;
            
            % Display the sharpened image in uiaxes1
            imshow(sharpened_img, 'Parent', app.UIAxes1);
        end

        % Callback function
        function ColorCorrectionMenuSelected(app, event)

% Define the custom color correction function
function corrected_img = custom_color_correction(img, min_values, max_values)
    % Get the number of color channels
    num_channels = size(img, 3);

    % Correct each color channel separately
    corrected_img = img;
    for channel = 1:num_channels
        corrected_img(:, :, channel) = imadjust(img(:, :, channel), ...
            [min_values(channel) max_values(channel)]);
    end
end

% Get the original image from uiaxes1
img = getimage(app.UIAxes1);
    if isempty(img)
               errordlg('Please load an image first')
               return
    end
% Get the number of color channels
num_channels = size(img, 3);

% Prompt the user to specify the minimum and maximum values for each channel
for channel = 1:num_channels
    answer = inputdlg(sprintf('Enter the minimum and maximum values for channel %d (e.g. 0 1):', channel));
    values = str2num(answer{1}); %#ok<ST2NM>
    min_values(channel) = values(1);
    max_values(channel) = values(2);
end

% Perform custom color correction on the image with specified minimum and maximum values
corrected_img = custom_color_correction(img, min_values, max_values);

% Display the corrected image in uiaxes1
imshow(corrected_img, 'Parent', app.UIAxes1);


        end

        % Menu selected function: TextMenu
        function TextMenuSelected(app, event)
           img = getimage(app.UIAxes1);
    if isempty(img)
               errordlg('Please load an image first')
               return
    end
persistent x_pos y_pos;
% Prompt the user to enter the text to add to the image
prompt = {'Enter the text:','Enter the color (e.g. ''red''):'};
dlg_title = 'Add Text';
num_lines = 1;
defaultans = {'Text','red'};
answer = inputdlg(prompt,dlg_title,num_lines,defaultans);

% Get the text and color from the user input
text_str = answer{1};
text_color = answer{2};


    % Get the position from a mouse click
    imshow(img);
    
    title('Click on the image to specify the position');
    [x_pos,y_pos] = ginput(1);


% Add the text to the image
img_with_text = insertText(img,[x_pos y_pos],text_str,'FontSize',18,'BoxOpacity',0,'TextColor',text_color);

% Display the image with text in uiaxes1
imshow(img_with_text, 'Parent', app.UIAxes1);
        end

        % Menu selected function: TransformMenu
        function TransformMenuSelected(app, event)
            
% Get the original image from uiaxes1
img = getimage(app.UIAxes1);

    if isempty(img)
               errordlg('Please load an image first')
               return
    end

% Specify a list of available transformations
transformations = {'Rotate', 'Scale', 'Warp'};

% Show the list of transformations in a dropdown list
[choice,ok] = listdlg('PromptString','Select the transformation:',...
                      'SelectionMode','single',...
                      'ListString',transformations);
if ~ok
    return;
end
transform_type = transformations{choice};

% Perform the selected transformation
switch transform_type
    case 'Rotate'
        % Prompt the user to enter the rotation angle
        prompt = {'Enter the rotation angle (degrees):'};
        dlg_title = 'Rotate Image';
        num_lines = 1;
        defaultans = {'90'};
        answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
        
        % Get the rotation angle from the user input
        angle = str2double(answer{1});
        
        % Rotate the image
        img_rotated = imrotate(img, angle);
        
        % Display the rotated image in uiaxes1
        imshow(img_rotated, 'Parent', app.UIAxes1);
      
        
    case 'Scale'
       % Prompt the user to enter the new size of the image
        prompt = {'Enter the new width (pixels):', 'Enter the new height (pixels):'};
        dlg_title = 'Resize Image';
        num_lines = 1;
        defaultans = {num2str(size(img, 2)), num2str(size(img, 1))};
        answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
        
        % Get the new size of the image from the user input
        new_width = str2double(answer{1});
        new_height = str2double(answer{2});
        
        % Resize the image
        img_resized = imresize(img, [new_height, new_width]);
        
        % Display the resized image in uiaxes1
        imshow(img_resized, 'Parent', app.UIAxes1);


    case 'Warp'
        % Prompt the user to enter the warping parameters
        prompt = {'Enter the x-scale factor:', 'Enter the y-scale factor:', 'Enter the x-shear factor:', 'Enter the y-shear factor:'};
        dlg_title = 'Warp Image';
        num_lines = 1;
        defaultans = {'2', '2', '0', '0'};
        answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
        
        % Get the warping parameters from the user input
        x_scale = str2double(answer{1});
        y_scale = str2double(answer{2});
        x_shear = str2double(answer{3});
        y_shear = str2double(answer{4});
        
        % Warp the image
        tform = affine2d([x_scale y_shear 0; x_shear y_scale 0; 0 0 1]);
        img_warped = imwarp(img, tform);
         % Display the warped image in uiaxes1
        imshow(img_warped, 'Parent', app.UIAxes1);
      
end
           
        end

        % Menu selected function: BrightnessMenu
        function BrightnessMenuSelected(app, event)
            % Get the original image from uiaxes1
    img = getimage(app.UIAxes1);
    
    if isempty(img)
               errordlg('Please load an image first')
               return
    end
    
    % Prompt the user to enter the brightness level
    prompt = {'Enter the brightness level [-255 255]:'};
    dlg_title = 'Brightness Adjustment';
    num_lines = 1;
    defaultans = {'50'};
    answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
    
    % Get the brightness level from the user input
    brightness = str2double(answer{1});
    
    % Adjust the brightness of the image
    img_adjusted = img + brightness;
    
    % Display the adjusted image in uiaxes1
    imshow(img_adjusted, 'Parent', app.UIAxes1);
        end

        % Menu selected function: ContrastMenu
        function ContrastMenuSelected(app, event)
             % Get the original image from uiaxes1
    img = getimage(app.UIAxes1);
    if isempty(img)
               errordlg('Please load an image first')
               return
    end
    value = inputdlg('Enter contrast value:');
    value = str2double(value);
    % Check if value is a number
    if isnumeric(value)
        % Normalize the value to the range [-1, 1]
        value = value / 100;
        % Convert the value to the range [0, 1]
        value = (value + 1) / 2;
        % Perform contrast stretching using imadjust
        adjustedImage = imadjust(img, [], [], value);
        % Display the adjusted image in uiaxes1
        imshow(adjustedImage, 'Parent', app.UIAxes1);
    else
        % Display error message if the input is not a number
        errordlg('Invalid input: Please enter a numerical value.');
    end
        end

        % Menu selected function: SaturationMenu
        function SaturationMenuSelected(app, event)
            % Get the original image from uiaxes1
    img = getimage(app.UIAxes1);
    if isempty(img)
               errordlg('Please load an image first')
               return
    end
    % Prompt the user to enter the saturation level
    prompt = {'Enter the saturation level [0 100]:'};
    dlg_title = 'Saturation Adjustment';
    num_lines = 1;
    defaultans = {'50'};
    answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
    
    % Get the saturation level from the user input
    saturation = str2double(answer{1});
    
    % Convert the image to the HSV color space
    img_hsv = rgb2hsv(img);

     % Adjust the saturation of the image
    img_hsv(:, :, 2) = img_hsv(:, :, 2) * saturation / 100;
    
    % Convert the image back to the RGB color space
    img_adjusted = hsv2rgb(img_hsv);
    
    % Display the adjusted image in uiaxes1
    imshow(img_adjusted, 'Parent', app.UIAxes1);
        end

        % Callback function
        function UndoButtonPushed(app, event)
   if length(history) > 1
      % Remove the latest item from the history
      history(end) = [];
      % Set the current image to the previous state
      image = history{end};
      % Update the image in the UI
      updateImage(app, image);
   end
        end

        % Callback function
        function RedoButtonPushed(app, event)
            z
        end

        % Menu selected function: LaplacianofGaussianMenu
        function LaplacianofGaussianMenuSelected(app, event)
              image = getappdata(app.UIFigure,'OriginalImg');
            if isempty(image)
               errordlg('Please load an image first')
               return
            end
            % Perform LoG edge detection
            sigma = 1;
            hsize = 3*sigma;
            h = fspecial('log', hsize, sigma);
            image_edges = imfilter(image, h);
            
            % Display the result in the UIAxes
            imshow(image_edges, 'Parent', app.UIAxes1);
        end

        % Callback function
        function MarrHildrethMenuSelected(app, event)
            % Get the current image
            image = getimage(app.UIAxes1);
            if isempty(image)
               errordlg('Please load an image first')
               return
            end
            % Perform Marr-Hildreth edge detection
            sigma = str2double(get(src, 'Label'));
            MarrHildreth = edge(image, 'log', [], sigma);
            
            % Display the result
            imshow(MarrHildreth, 'Parent', app.UIAxes1);
        end

        % Menu selected function: RedoMenu
        function RedoMenuSelected(app, event)
            stack2= getappdata(0,'stack2');
            if size(stack2)>0
                  item = stack2{end};
                 stack2(end) = [];
                setappdata(0,'stack2',stack2); 
                %im2=getappdata(0,'org');
                imshow(item,"Parent",app.UIAxes1);
                 stack= getappdata(0,'stack');
            stack{end+1} =getimage(app.UIAxes1);
             setappdata(0,'stack',stack);
             end
        end

        % Menu selected function: UndoMenu
        function UndoMenuSelected(app, event)
            stack= getappdata(0,'stack');
             if size(stack)>0
                  item = stack{end};
                 stack(end) = [];
                setappdata(0,'stack',stack); 
                %im2=getappdata(0,'org');
                imshow(item,"Parent",app.UIAxes);
                 stack2= getappdata(0,'stack2');
                stack2{end+1} =item;
                 setappdata(0,'stack2',stack2);
             end
        end

        % Menu selected function: morphologicalMenu
        function morphologicalMenuSelected(app, event)
              % check if there is an original image loaded
            originalImage = getimage(app.UIAxes1);
            if isempty(originalImage)
               errordlg('Please load an image first')
               return
            end
         
            st1=[1 1 1 
                     1 1 1
                     1 1 1];
                st2=[0 1 0 
                     1 1 1
                     0 1 0];
                st3=[0 1 0 
                     0 1 0
                     0 1 0]; 
                st4=[0 0 0 
                     1 1 1
                     0 0 0]; 


    % Create a new figure window
    morphologicalFilterFigure = figure;
    
    % Create a dropdown menu for the filter options
    filterDropdown = uicontrol('Style', 'popup',...
    'String', {'Erosion Cube', 'Erosion Circle', 'Erosion |', 'Erosion ---'},...
    'Position', [20 20 200 50]);
    
    % Add a button for the user to apply the selected filter
    applyFilterButton = uicontrol('Style', 'pushbutton',...
    'String', 'Apply Filter',...
    'Position', [20 70 200 50],...
    'Callback', @applyFilter);
    
    function applyFilter(src, event)
        % Get the selected filter option
        selectedFilter = filterDropdown.String{filterDropdown.Value};
        
        
        % Perform the selected filter
        switch selectedFilter
            case 'Erosion Cube'
                filteredImg = imerode(originalImage, st1);
            case 'Erosion Circle'
                filteredImg = imerode(originalImage, st2);
            case 'Erosion |'
                filteredImg = imerode(originalImage, st3);
            case 'Erosion ---'
                filteredImg = imerode(originalImage, st4);
        end
        
        %display the filtered image in the axes2
         imshow(filteredImg,'Parent',app.UIAxes1);
        % Close the morphological filter figure window
        %close(morphologicalFilterFigure);
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.ResetMenu = uimenu(app.FileMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.RedoMenu = uimenu(app.FileMenu);
            app.RedoMenu.MenuSelectedFcn = createCallbackFcn(app, @RedoMenuSelected, true);
            app.RedoMenu.Accelerator = 'y';
            app.RedoMenu.Text = 'Redo';
            app.UndoMenu = uimenu(app.FileMenu);
            app.UndoMenu.MenuSelectedFcn = createCallbackFcn(app, @UndoMenuSelected, true);
            app.UndoMenu.Accelerator = 'z';
            app.UndoMenu.Text = 'Undo';
            app.filters = uimenu(app.UIFigure);
            app.filters.Text = 'Filters';
            app.MoyenMenu = uimenu(app.filters);
            app.MoyenMenu.MenuSelectedFcn = createCallbackFcn(app, @MoyenMenuSelected, true);
            app.MoyenMenu.Text = 'Moyen';
            app.GaussienMenu = uimenu(app.filters);
            app.GaussienMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussienMenuSelected, true);
            app.GaussienMenu.Text = 'Gaussien';
            app.MedianMenu = uimenu(app.filters);
            app.MedianMenu.MenuSelectedFcn = createCallbackFcn(app, @MedianMenuSelected, true);
            app.MedianMenu.Text = 'Median';
            app.SharpenMenu = uimenu(app.filters);
            app.SharpenMenu.MenuSelectedFcn = createCallbackFcn(app, @SharpenMenuSelected, true);
            app.SharpenMenu.Text = 'Sharpen';
            app.morphologicalMenu = uimenu(app.filters);
            app.morphologicalMenu.MenuSelectedFcn = createCallbackFcn(app, @morphologicalMenuSelected, true);
            app.morphologicalMenu.Text = 'morphological';
            app.NoiseMenu = uimenu(app.UIFigure);
            app.NoiseMenu.Text = 'Noise';
            app.SaltPepperMenu = uimenu(app.NoiseMenu);
            app.SaltPepperMenu.MenuSelectedFcn = createCallbackFcn(app, @SaltPepperMenuSelected, true);
            app.SaltPepperMenu.Text = 'Salt&Pepper';
            app.GuassianMenu = uimenu(app.NoiseMenu);
            app.GuassianMenu.MenuSelectedFcn = createCallbackFcn(app, @GuassianMenuSelected, true);
            app.GuassianMenu.Text = 'Guassian';
            app.sinusoidalMenu = uimenu(app.NoiseMenu);
            app.sinusoidalMenu.MenuSelectedFcn = createCallbackFcn(app, @sinusoidalMenuSelected, true);
            app.sinusoidalMenu.Text = 'sinusoidal';
            app.DetectionMenu = uimenu(app.UIFigure);
            app.DetectionMenu.Text = 'Detection';
            app.EdgeMenu = uimenu(app.DetectionMenu);
            app.EdgeMenu.Text = 'Edge';
            app.SobelMenu = uimenu(app.EdgeMenu);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel';
            app.cannyMenu = uimenu(app.EdgeMenu);
            app.cannyMenu.MenuSelectedFcn = createCallbackFcn(app, @cannyMenuSelected, true);
            app.cannyMenu.Text = 'canny';
            app.prewittMenu = uimenu(app.EdgeMenu);
            app.prewittMenu.MenuSelectedFcn = createCallbackFcn(app, @prewittMenuSelected, true);
            app.prewittMenu.Text = 'prewitt';
            app.LaplacianofGaussianMenu = uimenu(app.EdgeMenu);
            app.LaplacianofGaussianMenu.MenuSelectedFcn = createCallbackFcn(app, @LaplacianofGaussianMenuSelected, true);
            app.LaplacianofGaussianMenu.Text = 'Laplacian of Gaussian';
            app.RGBMenu = uimenu(app.UIFigure);
            app.RGBMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBMenuSelected, true);
            app.RGBMenu.Text = 'RGB';
            app.seuillageMenu = uimenu(app.UIFigure);
            app.seuillageMenu.MenuSelectedFcn = createCallbackFcn(app, @seuillageMenuSelected, true);
            app.seuillageMenu.Text = 'seuillage';
            app.graph = uimenu(app.UIFigure);
            app.graph.Text = 'Graph';
            app.HistogramMenu = uimenu(app.graph);
            app.HistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramMenuSelected, true);
            app.HistogramMenu.Text = 'Histogram';
            app.HistogramequalizationMenu = uimenu(app.graph);
            app.HistogramequalizationMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramequalizationMenuSelected, true);
            app.HistogramequalizationMenu.Text = 'Histogram equalization';
            app.FourierTransform = uimenu(app.UIFigure);
            app.FourierTransform.MenuSelectedFcn = createCallbackFcn(app, @FourierTransformMenuSelected, true);
            app.FourierTransform.Text = 'FourierTransform';
            app.selectMenu = uimenu(app.UIFigure);
            app.selectMenu.MenuSelectedFcn = createCallbackFcn(app, @selectMenuSelected, true);
            app.selectMenu.Text = 'select';
            app.TextMenu = uimenu(app.UIFigure);
            app.TextMenu.MenuSelectedFcn = createCallbackFcn(app, @TextMenuSelected, true);
            app.TextMenu.Text = 'Text';
            app.TransformMenu = uimenu(app.UIFigure);
            app.TransformMenu.MenuSelectedFcn = createCallbackFcn(app, @TransformMenuSelected, true);
            app.TransformMenu.Text = 'Transform';
            app.adjustmentMenu = uimenu(app.UIFigure);
            app.adjustmentMenu.Text = 'adjustment';
            app.BrightnessMenu = uimenu(app.adjustmentMenu);
            app.BrightnessMenu.MenuSelectedFcn = createCallbackFcn(app, @BrightnessMenuSelected, true);
            app.BrightnessMenu.Text = 'Brightness';
            app.ContrastMenu = uimenu(app.adjustmentMenu);
            app.ContrastMenu.MenuSelectedFcn = createCallbackFcn(app, @ContrastMenuSelected, true);
            app.ContrastMenu.Text = ' Contrast';
            app.SaturationMenu = uimenu(app.adjustmentMenu);
            app.SaturationMenu.MenuSelectedFcn = createCallbackFcn(app, @SaturationMenuSelected, true);
            app.SaturationMenu.Text = 'Saturation';
            app.UIAxes1 = uiaxes(app.UIFigure);
            app.UIAxes1.XTick = [];
            app.UIAxes1.XTickLabel = '';
            app.UIAxes1.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end